classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Menu             matlab.ui.container.Menu
        ImportImageMenu  matlab.ui.container.Menu
        UIImage          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportImageMenu, Menu
        function ImportImageMenuSelected(app, event)
            % 打开文件选择对话框
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp;*.gif', 'Image Files (*.jpg, *.png, *.jpeg, *.bmp, *.gif)'});
            
            if file ~= 0  % 如果用户选择了文件
                imgPath = fullfile(path, file);
                
                % 将图像路径赋给 UIImage 组件的 ImageSource 属性
                app.UIImage.ImageSource = imgPath;  % 显示图片
            else
                disp('No image selected');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageMenuSelected, true);
            app.Menu.Text = 'Menu';
            app.ImportImageMenu = uimenu(app.Menu);
            app.ImportImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageMenuSelected, true);
            app.ImportImageMenu.Text = 'Import Image';
            app.UIImage = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end